classdef NewExperimentGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        LoadDataTab                   matlab.ui.container.Tab
        ImageFileLabel                matlab.ui.control.Label
        SelectButton                  matlab.ui.control.Button
        ExperimentNameEditFieldLabel  matlab.ui.control.Label
        ExperimentNameEditField       matlab.ui.control.EditField
        DateImagedDatePickerLabel     matlab.ui.control.Label
        DateImagedDatePicker          matlab.ui.control.DatePicker
        ExperimenterEditFieldLabel    matlab.ui.control.Label
        ExperimenterEditField         matlab.ui.control.EditField
        UITable                       matlab.ui.control.Table
        AddRowButton                  matlab.ui.control.Button
        RemoveRowButton               matlab.ui.control.Button
        ImagesTab                     matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        RawImagesTab                  matlab.ui.container.Tab
        i470_2_Axes                   matlab.ui.control.UIAxes
        i410_1_Axes                   matlab.ui.control.UIAxes
        i410_2_Axes                   matlab.ui.control.UIAxes
        iTL_Axes                      matlab.ui.control.UIAxes
        Label_2                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        i470_1_Axes                   matlab.ui.control.UIAxes
        TransmittedLightLabel         matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        RatioImagesTab                matlab.ui.container.Tab
        RatioPairOne                  matlab.ui.control.UIAxes
        RatioPairTwo                  matlab.ui.control.UIAxes
        RatioPair1Label               matlab.ui.control.Label
        RatioPair2Label               matlab.ui.control.Label
        PreviousFrameButton           matlab.ui.control.Button
        NextFrameButton               matlab.ui.control.Button
        FrameSlider                   matlab.ui.control.Slider
        PlotsTab                      matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        allImages % Description
        imageStacks % Description
    end
    
    methods (Access = private)
        
        function loadImageFile(app, filePath)
            app.allImages = loadTiffImageStack(filePath);
            
            app.imageStacks.iTL    = app.allImages(:,:,1:5:end);
            app.imageStacks.i470_1 = app.allImages(:,:,2:5:end);
            app.imageStacks.i410_1 = app.allImages(:,:,3:5:end);
            app.imageStacks.i470_2 = app.allImages(:,:,4:5:end);
            app.imageStacks.i410_2 = app.allImages(:,:,5:5:end);
            
            setRawImageFrames(app, 1);
            configureSlider(app, size(app.allImages, 3) / 5);
        end
        
        function setRawImageFrames(app, animalIdx)
            imshow(app.imageStacks.iTL(:,:,animalIdx),    [],       'Parent', app.iTL_Axes);
            imshow(app.imageStacks.i470_1(:,:,animalIdx), [0 2^16], 'Parent', app.i470_1_Axes);
            imshow(app.imageStacks.i470_2(:,:,animalIdx), [0 2^16], 'Parent', app.i470_2_Axes);
            imshow(app.imageStacks.i410_1(:,:,animalIdx), [0 2^16], 'Parent', app.i410_1_Axes);
            imshow(app.imageStacks.i410_2(:,:,animalIdx), [0 2^16], 'Parent', app.i410_2_Axes);
        end
        
        function configureSlider(app, nAnimals)
            app.FrameSlider.Limits = [1 nAnimals];
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = {'Default' 0 0};
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
%             [file,path] = uigetfile({'*.tif';'*.tiff'}, 'File Selector');
%             fullpathname = fullfile(path, file);
            fullPathName = "/Users/sean/code/wormAnalysis/data/NEW_DATA/2017-02-22_HD233_SAY47/2017-02-22_HD233_SAY47.tif";
            loadImageFile(app, fullPathName);
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            newData = {'Default' 0 0}; %sample data for each column
            app.UITable.Data = [app.UITable.Data;newData];
        end

        % Button pushed function: RemoveRowButton
        function RemoveRowButtonPushed(app, event)
            app.UITable.Data(end,:) = [];
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            app.FrameSlider.Value = round(app.FrameSlider.Value);
            setRawImageFrames(app, app.FrameSlider.Value);
        end

        % Button pushed function: PreviousFrameButton
        function PreviousFrameButtonPushed(app, event)
            app.FrameSlider.Value = round(app.FrameSlider.Value - 1);
            setRawImageFrames(app, app.FrameSlider.Value);
        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
            app.FrameSlider.Value = round(app.FrameSlider.Value + 1);
            setRawImageFrames(app, app.FrameSlider.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadDataTab = uitab(app.TabGroup);
            app.LoadDataTab.Title = 'Load Data';
            app.ImageFileLabel = uilabel(app.LoadDataTab);
            app.ImageFileLabel.Text = 'Image File';
            app.SelectButton = uibutton(app.LoadDataTab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.ExperimentNameEditFieldLabel = uilabel(app.LoadDataTab);
            app.ExperimentNameEditFieldLabel.Text = 'Experiment Name';
            app.ExperimentNameEditField = uieditfield(app.LoadDataTab, 'text');
            app.DateImagedDatePickerLabel = uilabel(app.LoadDataTab);
            app.DateImagedDatePickerLabel.Text = 'Date Imaged';
            app.DateImagedDatePicker = uidatepicker(app.LoadDataTab);
            app.ExperimenterEditFieldLabel = uilabel(app.LoadDataTab);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.ExperimenterEditField = uieditfield(app.LoadDataTab, 'text');
            app.UITable = uitable(app.LoadDataTab);
            app.UITable.ColumnName = {'Strain'; 'Start Animal'; 'End Animal'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true];
            app.AddRowButton = uibutton(app.LoadDataTab, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = 'Add Row';
            app.RemoveRowButton = uibutton(app.LoadDataTab, 'push');
            app.RemoveRowButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRowButtonPushed, true);
            app.RemoveRowButton.Text = 'Remove Row';
            app.ImagesTab = uitab(app.TabGroup);
            app.ImagesTab.Title = 'Images';
            app.TabGroup2 = uitabgroup(app.ImagesTab);
            app.RawImagesTab = uitab(app.TabGroup2);
            app.RawImagesTab.Title = 'Raw Images';
            app.TransmittedLightLabel = uilabel(app.RawImagesTab);
            app.TransmittedLightLabel.Text = 'Transmitted Light';
            app.Label = uilabel(app.RawImagesTab);
            app.Label.Text = '410-1';
            app.i470_1_Axes = uiaxes(app.RawImagesTab);
            app.i470_1_Axes.XLim = [1 174];
            app.i470_1_Axes.YLim = [1 130];
            app.i470_1_Axes.XTick = [];
            app.i470_1_Axes.XTickLabel = '';
            app.i470_1_Axes.YTick = [];
            app.i470_1_Axes.YTickLabel = '';
            app.i470_2_Axes = uiaxes(app.RawImagesTab);
            app.i470_2_Axes.XLim = [1 174];
            app.i470_2_Axes.YLim = [1 130];
            app.i470_2_Axes.XTick = [];
            app.i470_2_Axes.XTickLabel = '';
            app.i470_2_Axes.YTick = [];
            app.i470_2_Axes.YTickLabel = '';
            app.i410_1_Axes = uiaxes(app.RawImagesTab);
            app.i410_1_Axes.XLim = [1 174];
            app.i410_1_Axes.YLim = [1 130];
            app.i410_1_Axes.XTick = [];
            app.i410_1_Axes.XTickLabel = '';
            app.i410_1_Axes.YTick = [];
            app.i410_1_Axes.YTickLabel = '';
            app.i410_2_Axes = uiaxes(app.RawImagesTab);
            app.i410_2_Axes.XLim = [1 174];
            app.i410_2_Axes.YLim = [1 130];
            app.i410_2_Axes.XTick = [];
            app.i410_2_Axes.XTickLabel = '';
            app.i410_2_Axes.YTick = [];
            app.i410_2_Axes.YTickLabel = '';
            app.iTL_Axes = uiaxes(app.RawImagesTab);
            app.iTL_Axes.XLim = [1 174];
            app.iTL_Axes.YLim = [1 130];
            app.iTL_Axes.XTick = [];
            app.iTL_Axes.XTickLabel = '';
            app.iTL_Axes.YTick = [];
            app.iTL_Axes.YTickLabel = '';
            app.Label_2 = uilabel(app.RawImagesTab);
            app.Label_2.Text = '410-2';
            app.Label_3 = uilabel(app.RawImagesTab);
            app.Label_3.Text = '470-1';
            app.Label_4 = uilabel(app.RawImagesTab);
            app.Label_4.Text = '470-2';
            app.RatioImagesTab = uitab(app.TabGroup2);
            app.RatioImagesTab.Title = 'Ratio Images';
            app.RatioPairOne = uiaxes(app.RatioImagesTab);
            app.RatioPairOne.XLim = [1 174];
            app.RatioPairOne.YLim = [1 130];
            app.RatioPairOne.XTick = [];
            app.RatioPairOne.XTickLabel = '';
            app.RatioPairOne.YTick = [];
            app.RatioPairOne.YTickLabel = '';
            app.RatioPairTwo = uiaxes(app.RatioImagesTab);
            app.RatioPairTwo.XLim = [1 174];
            app.RatioPairTwo.YLim = [1 130];
            app.RatioPairTwo.XTick = [];
            app.RatioPairTwo.XTickLabel = '';
            app.RatioPairTwo.YTick = [];
            app.RatioPairTwo.YTickLabel = '';
            app.RatioPair1Label = uilabel(app.RatioImagesTab);
            app.RatioPair1Label.Text = 'Ratio Pair 1';
            app.RatioPair2Label = uilabel(app.RatioImagesTab);
            app.RatioPair2Label.Text = 'Ratio Pair 2';
            app.FrameSlider = uislider(app.ImagesTab);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.PreviousFrameButton = uibutton(app.ImagesTab, 'push');
            app.PreviousFrameButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousFrameButtonPushed, true);
            app.PreviousFrameButton.Text = '<';
            app.NextFrameButton = uibutton(app.ImagesTab, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Text = '>';
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.UIAxes = uiaxes(app.PlotsTab);
            title(app.UIAxes, 'I410')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.PlotsTab);
            title(app.UIAxes2, 'R')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes_2 = uiaxes(app.PlotsTab);
            title(app.UIAxes_2, 'I410')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = NewExperimentGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end